classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        outputPanel                  matlab.ui.container.Panel
        secondderivativet0EditField  matlab.ui.control.NumericEditField
        secondderivativet0EditFieldLabel  matlab.ui.control.Label
        firstderiviativet0EditField  matlab.ui.control.NumericEditField
        firstderiviativet0Label      matlab.ui.control.Label
        thirdmomentEditField         matlab.ui.control.NumericEditField
        thirdmomentEditFieldLabel    matlab.ui.control.Label
        varianceEditField            matlab.ui.control.NumericEditField
        varianceEditFieldLabel       matlab.ui.control.Label
        meanEditField                matlab.ui.control.NumericEditField
        meanEditFieldLabel           matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        inputPanel                   matlab.ui.container.Panel
        CalculateButton              matlab.ui.control.Button
        importfileButton             matlab.ui.control.Button
        filenameEditField            matlab.ui.control.EditField
        filenameEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: filenameEditField
        function filenameEditFieldValueChanged(app, event)
            value = app.filenameEditField.Value;
        end

        % Button pushed function: importfileButton
        function importfileButtonPushed(app, event)
           [filename,path] =uigetfile();
           app.filenameEditField.Value = filename ; 
           
         
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
              data = load(   app.filenameEditField.Value);
            data = struct2cell(data); % Convert data to cell array

            % Extract data from cell array
            data = data{1};


% Calculate the mean
mean_value = sum(data) / numel(data);

% Calculate the variance
sum_squared_diff = sum((data - mean_value).^2);
variance_value = sum_squared_diff / numel(data);

% Calculate the third moment
sum_cubed_diff = sum((data - mean_value).^3);
third_moment = sum_cubed_diff / numel(data);


t_values = linspace(0, 2, 100); % Define t values
n = numel(data); % Number of data points

% Calculate MGF M(t) manually
    M_t = zeros(size(t_values));
    for i = 1:length(t_values)
        t = t_values(i);
        M_t(i) = sum(exp(t * data)) / n;
    end

    % Calculate the first derivative of M(t) manually
    first_derivative_M_t = zeros(size(t_values) - [0 1]);
    for i = 1:length(t_values) - 1
        first_derivative_M_t(i) = (M_t(i + 1) - M_t(i)) / (t_values(i + 1) - t_values(i));
    end

    % Calculate the second derivative of M(t) manually
    second_derivative_M_t = zeros(size(first_derivative_M_t) - [0 1]);
    for i = 1:length(first_derivative_M_t) - 1
        second_derivative_M_t(i) = (first_derivative_M_t(i + 1) - first_derivative_M_t(i)) / (t_values(i + 1) - t_values(i));
    end

    
    % Update the edit fields for derivatives at t=0
    app.firstderiviativet0EditField.Value = first_derivative_M_t(1);
    app.secondderivativet0EditField.Value = second_derivative_M_t(1);


% 
% % Calculate the first derivative of M(t) and its value at t = 0
% first_derivative_M_t = diff(M_t) ./ diff(t_values);
% first_derivative_at_t_0 = first_derivative_M_t(1);
% 
% % Calculate the second derivative of M(t) and its value at t = 0
% second_derivative_M_t = diff(first_derivative_M_t) ./ diff(t_values(1:end-1));
% second_derivative_at_t_0 = second_derivative_M_t(1);
% 


app.meanEditField.Value = mean_value ; 
app.varianceEditField.Value = variance_value ; 
app.thirdmomentEditField.Value=third_moment ; 

% 
% app.firstderiviativet0EditField.Value = first_derivative_at_t_0 ; 
% app.secondderivativet0EditField.Value = second_derivative_at_t_0 ; 
% 
% 

%plotting 

% Plot for the first graph
plot(app.UIAxes, t_values, M_t);
title(app.UIAxes, 'MGF plot ');
xlabel(app.UIAxes, 'Time (t)');
ylabel(app.UIAxes, 'M(t)');

% Plot for the second graph
plot(app.UIAxes2, t_values(1:end-1), first_derivative_M_t);
title(app.UIAxes2, 'First derivatives Plot');
xlabel(app.UIAxes2, 'Time (t)');
ylabel(app.UIAxes2, 'First Derivative of M(t)');

% Plot for the third graph
plot(app.UIAxes3, t_values(1:end-2), second_derivative_M_t);
title(app.UIAxes3, 'second derivatives plot');
xlabel(app.UIAxes3, 'Time (t)');
ylabel(app.UIAxes3, 'Second Derivative of M(t)');

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputPanel = uipanel(app.UIFigure);
            app.inputPanel.Title = 'input ';
            app.filenameEditFieldLabel = uilabel(app.inputPanel);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.inputPanel, 'text');
            app.filenameEditField.ValueChangedFcn = createCallbackFcn(app, @filenameEditFieldValueChanged, true);
            app.importfileButton = uibutton(app.inputPanel, 'push');
            app.importfileButton.ButtonPushedFcn = createCallbackFcn(app, @importfileButtonPushed, true);
            app.importfileButton.Icon = fullfile(pathToMLAPP, 'images.png');
            app.importfileButton.Text = 'import file ';
            app.CalculateButton = uibutton(app.inputPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Icon = fullfile(pathToMLAPP, 'download.png');
            app.CalculateButton.Text = 'Calculate';
            app.outputPanel = uipanel(app.UIFigure);
            app.outputPanel.Title = 'output';
            app.UIAxes = uiaxes(app.outputPanel);
            title(app.UIAxes, 'MGF')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.outputPanel);
            title(app.UIAxes2, 'first derivative ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.outputPanel);
            title(app.UIAxes3, 'second derivative')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.meanEditFieldLabel = uilabel(app.outputPanel);
            app.meanEditFieldLabel.Text = 'mean ';
            app.meanEditField = uieditfield(app.outputPanel, 'numeric');
            app.varianceEditFieldLabel = uilabel(app.outputPanel);
            app.varianceEditFieldLabel.Text = 'variance ';
            app.varianceEditField = uieditfield(app.outputPanel, 'numeric');
            app.thirdmomentEditFieldLabel = uilabel(app.outputPanel);
            app.thirdmomentEditFieldLabel.Text = 'thirdmoment ';
            app.thirdmomentEditField = uieditfield(app.outputPanel, 'numeric');
            app.firstderiviativet0Label = uilabel(app.outputPanel);
            app.firstderiviativet0Label.Text = 'firstderiviativet0';
            app.firstderiviativet0EditField = uieditfield(app.outputPanel, 'numeric');
            app.secondderivativet0EditFieldLabel = uilabel(app.outputPanel);
            app.secondderivativet0EditFieldLabel.Text = 'secondderivativet0';
            app.secondderivativet0EditField = uieditfield(app.outputPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end