classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        OutputPanel             matlab.ui.container.Panel
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
        InputPanel              matlab.ui.container.Panel
        EntertheindexofthesamplefunctionNEditField  matlab.ui.control.NumericEditField
        EntertheindexofthesamplefunctionNEditFieldLabel  matlab.ui.control.Label
        EnterthenumberofsamplefunctionsMEditField  matlab.ui.control.NumericEditField
        EnterthenumberofsamplefunctionsMEditFieldLabel  matlab.ui.control.Label
        CalculateButton         matlab.ui.control.Button
        FileNameEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
        ImportFileButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
  if isempty(app.FileNameEditField.Value)
        uialert(app.UIFigure, 'No file selected. Please import a file first.', 'File Error');
        return;
    end

    % Check if file exists
    if ~isfile(app.FileNameEditField.Value)
        uialert(app.UIFigure, 'The specified file does not exist.', 'File Error');
        return;
    end

    % Load the data using the full file path
    try
        loaded_data = load(app.FileNameEditField.Value);
    catch
        uialert(app.UIFigure, 'Error loading the file.', 'File Error');
        return;
    end

    % Check for required variables in the loaded data
    if ~all(isfield(loaded_data, {'X', 't'}))
        uialert(app.UIFigure, 'The file does not contain the required variables X and t.', 'Data Error');
        return;
    end

% Access the 'X' and 't' variables from the loaded data
amplitude = loaded_data.X;  % Assuming 'X' contains amplitude values
time = loaded_data.t;  % Assuming 't' contains corresponding time values

% Get the number of sample functions from the app
M = app.EnterthenumberofsamplefunctionsMEditField.Value;

% Create the plot in app.UIaxis2
plotHandle = plot(app.UIAxes, time, amplitude(1, :)); % Plot the first sample function
xlabel(app.UIAxes, 'Time');
ylabel(app.UIAxes, 'Amplitude');
title(app.UIAxes, sprintf('Plot of %d Sample Functions of Stochastic Process', M));

% Hold on and plot the rest of the sample functions
hold(app.UIAxes, 'on');
for i = 2:M
    plot(app.UIAxes, time, amplitude(i, :));
end
hold(app.UIAxes, 'off');

% Calculate ensemble mean
ensemble_mean = mean(amplitude, 1);

% Plot ensemble mean in app.UIaxis2
plot(app.UIAxes2, time, ensemble_mean);
xlabel(app.UIAxes2, 'Time');
ylabel(app.UIAxes2, 'Ensemble Mean');
title(app.UIAxes2, 'Ensemble Mean of Stochastic Process');

% Display ensemble mean values
fprintf('Ensemble mean is a function of time with values.\n');
fprintf('%g ', ensemble_mean);

% Save ensemble mean values to a file
output_file = fopen('ensemble_mean_values.txt', 'w');
fprintf(output_file, 'Ensemble mean is a function of time with values.\n');
fprintf(output_file, '%g ', ensemble_mean);
fclose(output_file);

% Calculate the statistical auto-correlation function manually
N = length(ensemble_mean);
statistical_acf = zeros(1, 2 * N - 1);

for lag = 1:length(statistical_acf)
    % Compute ACF for each lag
    sum_corr = 0;
    for t = 1:N - abs(lag)
        if lag >= 0
            sum_corr = sum_corr + ensemble_mean(t) * ensemble_mean(t + lag);
        else
            sum_corr = sum_corr + ensemble_mean(t - lag) * ensemble_mean(t);
        end
    end
    statistical_acf(lag) = sum_corr / (N - abs(lag));
end

% Create lags corresponding to the ACF
lags = -length(ensemble_mean) + 1 : length(ensemble_mean) - 1;

% Plot statistical auto-correlation function in app.UIAxes3
plot(app.UIAxes3, lags, statistical_acf);
xlabel(app.UIAxes3, 'Lag');
ylabel(app.UIAxes3, 'Statistical Auto-correlation Function');
title(app.UIAxes3, 'Statistical Auto-correlation Function of Stochastic Process');


% Calculate and plot the statistical auto-correlation function
statistical_acf = xcorr(ensemble_mean, 'biased');
lags = -length(ensemble_mean) + 1 : length(ensemble_mean) - 1;

% Plot statistical auto-correlation function in app.UIAxes3
plot(app.UIAxes3, lags, statistical_acf);
xlabel(app.UIAxes3, 'Lag');
ylabel(app.UIAxes3, 'Statistical Auto-correlation Function');
title(app.UIAxes3, 'Statistical Auto-correlation Function of Stochastic Process');

% Display statistical auto-correlation function values
fprintf('ACF is a function of time with values.\n');
fprintf('%g ', statistical_acf);

% Save statistical auto-correlation function values to a file
output_file_acf = fopen('statistical_acf_values.txt', 'w');
fprintf(output_file_acf, 'ACF is a function of time with values.\n');
fprintf(output_file_acf, '%g ', statistical_acf);
fclose(output_file_acf);


% Assuming 'M' is the total number of sample functions
max_lag = min(length(amplitude(1, :)), 200); % Maximum lag size for computation
ACF_matrix = zeros(M, M, max_lag); % Initialize matrix to store ACF values

% Calculating the autocorrelation function manually
for i = 1:M
    for j = 1:M
        % Calculate the ACF up to the specified maximum lag
        A = amplitude(i, :);
        B = amplitude(j, :);
        
        % Calculate ACF for each lag
        for lag = 1:max_lag
            R = 0;
            N = length(A);
            
            for t = 1:(N - lag)
                R = R + (A(t) * B(t + lag));
            end
            
            ACF_matrix(i, j, lag) = R / (N - lag);
        end
    end
end

% Creating 3D plot of ACF
figure;
[X, Y] = meshgrid(1:M, 1:M);

for lag = 1:max_lag
    surf(X, Y, squeeze(ACF_matrix(:, :, lag)));
    xlabel('Sample Function i');
    ylabel('Sample Function j');
    zlabel('ACF');
    title(sprintf('Auto-Correlation Function at Lag %d', lag));
    pause(0.1); % Optional: pause to view each lag's plot
end

% Calculate and plot the power spectral density of the process
Fs = 1 / (time(2) - time(1)); % Sampling frequency

% Plot the power spectral density directly in app.UIAxes4
for i = 1:M
    [Pxx, F] = periodogram(amplitude(i, :), [], [], Fs, 'power');
    plot(app.UIAxes4, F, 10*log10(Pxx));
    hold(app.UIAxes4, 'on');
end

xlabel(app.UIAxes4, 'Frequency (Hz)');
ylabel(app.UIAxes4, 'Power/Frequency (dB/Hz)');
title(app.UIAxes4, 'Power Spectral Density of Stochastic Process');
hold(app.UIAxes4, 'off');


% Calculating the total average power of the process
total_power = 0;
Fs = 1 / (time(2) - time(1)); % Sampling frequency

for i = 1:size(amplitude, 1)
    [Pxx, ~] = periodogram(amplitude(i, :), [], [], Fs);
    total_power = total_power + sum(Pxx);
end

total_avg_power = total_power / size(amplitude, 1);

% Display total average power of the process
fprintf('Total average power of the process: %f\n', total_avg_power);

% Save total average power to a file
output_file_power = fopen('total_avg_power.txt', 'w');
fprintf(output_file_power, 'Total average power of the process: %f\n', total_avg_power);
fclose(output_file_power);

         
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
              [filename,path] =uigetfile();
           app.FileNameEditField.Value = filename ; 
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.ImportFileButton = uibutton(app.InputPanel, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Icon = fullfile(pathToMLAPP, 'images.png');
            app.ImportFileButton.Text = 'Import File';
            app.FileNameEditFieldLabel = uilabel(app.InputPanel);
            app.FileNameEditFieldLabel.Text = 'File Name ';
            app.FileNameEditField = uieditfield(app.InputPanel, 'text');
            app.CalculateButton = uibutton(app.InputPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Icon = fullfile(pathToMLAPP, 'download.png');
            app.CalculateButton.Text = 'Calculate';
            app.EnterthenumberofsamplefunctionsMEditFieldLabel = uilabel(app.InputPanel);
            app.EnterthenumberofsamplefunctionsMEditFieldLabel.Text = 'Enter the number of sample functions M';
            app.EnterthenumberofsamplefunctionsMEditField = uieditfield(app.InputPanel, 'numeric');
            app.EntertheindexofthesamplefunctionNEditFieldLabel = uilabel(app.InputPanel);
            app.EntertheindexofthesamplefunctionNEditFieldLabel.Text = 'Enter the index of the sample function N';
            app.EntertheindexofthesamplefunctionNEditField = uieditfield(app.InputPanel, 'numeric');
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output ';
            app.UIAxes = uiaxes(app.OutputPanel);
            title(app.UIAxes, 'M sample functions of the ensemble')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.OutputPanel);
            title(app.UIAxes2, 'ensemble mean')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.OutputPanel);
            title(app.UIAxes3, 'statistical auto-correlation')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.OutputPanel);
            title(app.UIAxes4, 'the power spectral density')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end