classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        HelloPanel               matlab.ui.container.Panel
        DoneButton               matlab.ui.control.Button
        choosemodeDropDown       matlab.ui.control.DropDown
        choosemodeDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
if strcmp(app.choosemodeDropDown.Value, 'Random Variable')
    app = app1(); % Creates an object of app1 class if the condition is met

else 

 app = app2(); % Creates an object of app1 class if the condition is met


end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HelloPanel = uipanel(app.UIFigure);
            app.HelloPanel.Title = 'Hello ';
            app.choosemodeDropDownLabel = uilabel(app.HelloPanel);
            app.choosemodeDropDownLabel.Text = 'choose mode';
            app.choosemodeDropDown = uidropdown(app.HelloPanel);
            app.choosemodeDropDown.Items = {'Random Variable', 'Random Process '};
            app.choosemodeDropDown.Value = 'Random Variable';
            app.DoneButton = uibutton(app.HelloPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end